classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        cartel_entrada  matlab.ui.control.Label
        placa_parking   matlab.ui.control.Label
        date            matlab.ui.control.Label
        estado_barrera  matlab.ui.control.Label
        button          matlab.ui.control.Button
        numCoches       matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Description
    end  
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind({'port'},{'COM4'}));
            app.s = serialport("COM4",19200,"Timeout",0.5);  
            configureTerminator(app.s, "CR/LF", "CR");
            
            
            global numCoches max reloj DateVector Date
            numCoches = 0;
            max = 200; 
            reloj = clock;
            DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
            Date = datestr(DateVector);
            
            app.estado_barrera.Text = "BARRERA CERRADA";
            app.numCoches.Text = num2str(max-numCoches);
            app.date.Text = Date;
            
            while true
                app.estado_barrera.Text = "BARRERA CERRADA";
                app.numCoches.Text = num2str(max-numCoches);
                
                reloj = clock;
                DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
                Date = datestr(DateVector);
                app.date.Text = Date;
                
                if (num2str(readline(app.s)) == "2") 
                    if (num2str(readline(app.s)) == "0")
                         app.estado_barrera.Text = "Solicitud Pendiente"    
                         pause(1);
                         if(max-numCoches > 0)
                            numCoches = numCoches + 1;
                            app.estado_barrera.Text = "Solictud Aceptada"
                            app.numCoches.Text = num2str(max-numCoches);
                            app.date.Text = Date;
                    
                            app.s.writeline("1");
                         else
                            app.estado_barrera.Text = "Solicitud Denegada"
                         end                  
                         
                    end
                end
                
                flush(app.s,"input");
                
            end
            
            
        end

        % Button pushed function: button
        function buttonButtonPushed(app, event)
            global numCoches max reloj DateVector Date
            
            reloj = clock;
            DateVector = [reloj(1),reloj(2),reloj(3),reloj(4),reloj(5),reloj(6)];
            Date = datestr(DateVector);
            
            writeline(app.s,"0");
            %pause(2); %borrar. Solo para prueba simulacion puerto virtual
            if(num2str(readline(app.s)) == "2")
                flush(app.s,"input");
                                
                app.estado_barrera.Text = "Solcitud Pendiente ...";
               
                pause(1); %Simula que la maquina esta buscando si hay o no sitio
                
                flush(app.s,"input");   %Limpieza de buffer por si se han 
                                        %enviado mensajes repetidos
                
                if(max-numCoches > 0)   %Se comprueba que haya espacio para mas
                                        %coches
                    numCoches = numCoches + 1;
                    
                    writeline(app.s, "1");  %Se envia mensaje de que si hay sitio
                    
                    app.estado_barrera.Text = "Solictud Aceptada"
                    app.numCoches.Text = num2str(max-numCoches);
                    app.date.Text = Date(13:end);
                    
                else
                    app.estado_barrera.Text = "Solicitud Denegada"
                end
                            
                pause(1); %Da un tiempo de un segundo para poder leer mensaje
                
    %             estado = readline(app.s);                 
    %            
    %             while ~(num2str(readline(app.s)) == "1")
    %                 %Espera a que no haya coche
    %             end
                
                % Ahora debería volver al loop de la funcion startFunc()
                % e imprimir "Barrera CERRADA" 
            end
            flush(app.s,"input");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cartel_entrada = uilabel(app.UIFigure);
            app.cartel_entrada.Text = {'ENTRADA PARKING'; 'UNIVERSIDAD'};
            app.placa_parking = uilabel(app.UIFigure);
            app.placa_parking.Text = 'P';
            app.date = uilabel(app.UIFigure);
            app.estado_barrera = uilabel(app.UIFigure);
            app.button = uibutton(app.UIFigure, 'push');
            app.button.ButtonPushedFcn = createCallbackFcn(app, @buttonButtonPushed, true);
            app.button.Text = '';
            app.numCoches = uilabel(app.UIFigure);
            app.numCoches.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end